classdef HyperCubeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        RGB_PreviewPanel       matlab.ui.container.Panel
        RGBAxes                matlab.ui.control.UIAxes
        Spectral_PreviewPanel  matlab.ui.container.Panel
        SpectralAxes           matlab.ui.control.UIAxes
        LoadDataButton         matlab.ui.control.Button
        Tab2                   matlab.ui.container.Tab
    end

    
    properties (Access = private)
        HyperCubeInstance % Instance třídy HyperCube
        filepath  % Cesta ke složce s fotkami 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            folder = uigetdir(pwd, 'Vyber složku s BMP');
            if isequal(folder, 0)
                return; % cancel
            end
            try
                app.HyperCubeInstance = HyperCube(folder); % uloží se do property, dostupné všude
                % RGB náhled (metoda GetAsRGB vrací už normalizovaný obraz)
                rgbImage = app.HyperCubeInstance.GetAsRGB();
                imshow(rgbImage, 'Parent', app.RGBAxes); % RGBAxes = UIAxes pro náhled
                % případně povol další prvky, např. slider, tlačítka…
            catch ME
                uialert(app.UIFigure, ME.message, 'Chyba při načítání');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.LoadDataButton = uibutton(app.Tab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.Spectral_PreviewPanel = uipanel(app.Tab);
            app.Spectral_PreviewPanel.Title = 'Spectrum';
            app.SpectralAxes = uiaxes(app.Spectral_PreviewPanel);
            app.SpectralAxes.XTick = [];
            app.SpectralAxes.XTickLabel = '';
            app.SpectralAxes.YTick = [];
            app.SpectralAxes.YTickLabel = '';
            app.SpectralAxes.ZTick = [];
            app.RGB_PreviewPanel = uipanel(app.Tab);
            app.RGB_PreviewPanel.Title = 'RGB';
            app.RGBAxes = uiaxes(app.RGB_PreviewPanel);
            app.RGBAxes.XTick = [];
            app.RGBAxes.XTickLabel = '';
            app.RGBAxes.YTick = [];
            app.RGBAxes.YTickLabel = '';
            app.RGBAxes.ZTick = [];
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HyperCubeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end